classdef Group_13_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AudioPlayerDemodulatedButton  matlab.ui.control.Button
        TextInputLabel                matlab.ui.control.Label
        TextArea_2                    matlab.ui.control.TextArea
        ImageOutputLabel_2            matlab.ui.control.Label
        ImageInputLabel               matlab.ui.control.Label
        TextOutputLabel               matlab.ui.control.Label
        Group13ChandrahasVeeragandham2022A3PS0675GLabel  matlab.ui.control.Label
        SNRindBEditField              matlab.ui.control.NumericEditField
        SNRindBEditFieldLabel         matlab.ui.control.Label
        ModulateButton                matlab.ui.control.Button
        AudioPlayerSourceButton       matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        DemodulateButton              matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        ConstellationTab              matlab.ui.container.Tab
        PSDTab                        matlab.ui.container.Tab
        TimeDomainTab                 matlab.ui.container.Tab
        ModulationDropDown            matlab.ui.control.DropDown
        ModulationDropDownLabel       matlab.ui.control.Label
        ChannelCodingDropDown         matlab.ui.control.DropDown
        ChannelCodingDropDownLabel    matlab.ui.control.Label
        SourceDataDropDown            matlab.ui.control.DropDown
        SourceDataDropDownLabel       matlab.ui.control.Label
        UIAxes2_2                     matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        FileData                    % Original binary data of the file
        EncodedData                 % Encoded data using the selected coding scheme
        ModulatedSignal             % Store modulated signal here
        TransmittedSignal           % Signal which is transmitted through AWGN channel
        DemodulatedData             % Store demodulated data
        SourceDataType              % Stores the current source data type
        padc                        % padding used in channel encoding
        padm                        % padding used in modulation
        H                           % Parity Check Matrix
        G                           % Generator Matrix
        n                           % Code word length
        k                           % message length
        nsamp                       % Description
    

        imgData % Description
        Fs % Description
        decodedBits % Final Output Data
    end
    methods (Access = public)

        function ModulatedSignal = modulateData(app, EncodedData, modulationType, SNR)
        % Convert data to binary if it's not already
        if ~islogical(EncodedData) && ~iscell(EncodedData)
            if ischar(EncodedData)
            EncodedData = de2bi(EncodedData, 8)';
            elseif isnumeric(EncodedData)
            EncodedData = de2bi(EncodedData, 'left-msb')';
            else
            error('Unsupported data type for modulation');
            end
        end

    % Ensure data is a row vector
        if ~isrow(EncodedData)
            EncodedData = EncodedData(:)';
        end

    % Calculate required bits per symbol based on modulation type
        switch modulationType
            case 'QPSK'
                bitsPerSymbol = 2;
            case '16 QAM'
                bitsPerSymbol = 4;
            case '64 QAM'
                bitsPerSymbol = 6;
            case '16 FSK'
                bitsPerSymbol = 4;
            case '64 FSK'
                bitsPerSymbol = 6;
            otherwise
                error('Unsupported modulation type');
        end

    % Calculate padding needed
        totalBits = numel(EncodedData);
        app.padm = mod(-totalBits, bitsPerSymbol);
        if app.padm> 0
            EncodedData = [EncodedData, zeros(1, app.padm)];
        end

    % Reshape data into symbol groups
        dataSymbols = reshape(EncodedData, bitsPerSymbol, []).';
        numSymbols = size(dataSymbols, 1);

    % Define FSK parameters
        app.Fs = 16;  % Sampling frequency (Hz)
        symbolRate = 1000;  % Symbol rate (symbols/second)
        samplesPerSymbol = 8;
        
    % Perform modulation based on scheme
        switch modulationType
            case 'QPSK'
                symbolValues = bi2de(dataSymbols, 'left-msb');
                ModulatedSignal = pskmod(symbolValues, 4);

            case '16 QAM'
                symbolValues = bi2de(dataSymbols, 'left-msb');
                ModulatedSignal = qammod(symbolValues, 16);

            case '64 QAM'
                symbolValues = bi2de(dataSymbols, 'left-msb');
                ModulatedSignal = qammod(symbolValues, 64);

            case '16 FSK'
                symbolValues = bi2de(dataSymbols, 'left-msb');
                % Configure FSK modulation parameters for 16-FSK
                M = 16;  % Number of frequency symbols
                
                app.nsamp = samplesPerSymbol;  % Number of samples per symbol
            
                % Create FSK modulator object with adjusted parameters
                
            
                ModulatedSignal = fskmod(symbolValues,M,4000,5,64000);

            case '64 FSK'
                symbolValues = bi2de(dataSymbols, 'left-msb');
                % Configure FSK modulation parameters for 64-FSK
                M = 64;  % Number of frequency symbols
                app.nsamp = samplesPerSymbol;  % Number of samples per symbol
            
                % Create FSK modulator object with adjusted parameters
                
                ModulatedSignal = fskmod(symbolValues,M,4000,10,256000);
        end

    % Add AWGN noise based on the selected SNR
        app.TransmittedSignal = awgn(ModulatedSignal, SNR, 'measured');
    end

    function DemodulatedData = demodulateData(app, TransmittedSignal, modulationType)
    % Ensure modulationType is a character array
        if iscell(modulationType)
            modulationType = char(modulationType);
        elseif isstring(modulationType)
            modulationType = char(modulationType);
        end
    
    % Get bitsPerSymbol based on modulation type
        switch modulationType
            case 'QPSK'
                bitsPerSymbol = 2;
            case '16 QAM'
                bitsPerSymbol = 4;
            case '64 QAM'
                bitsPerSymbol = 6;
            case '16 FSK'
                bitsPerSymbol = 4;
            case '64 FSK'
                bitsPerSymbol = 6;
            otherwise
                error('Unsupported modulation type: %s', modulationType);
        end
    
    % Implement demodulation based on the selected modulation scheme
        switch modulationType
            case 'QPSK'
                demodSymbols = pskdemod(app.TransmittedSignal, 4);
                DemodulatedData = de2bi(mod(demodSymbols, 4), bitsPerSymbol, 'left-msb');
    
            case '16 QAM'
                demodSymbols = qamdemod(app.TransmittedSignal, 16);
                DemodulatedData = de2bi(mod(demodSymbols, 16), bitsPerSymbol, 'left-msb');
            
            case '64 QAM'
                demodSymbols = qamdemod(app.TransmittedSignal, 64);
                DemodulatedData = de2bi(mod(demodSymbols, 64), bitsPerSymbol, 'left-msb');
            
            case '16 FSK'
                M = 16;
                demodSymbols = fskdemod(app.TransmittedSignal,M,4000,5,64000);
                DemodulatedData = de2bi(demodSymbols, bitsPerSymbol, 'left-msb');
            case '64 FSK'
                M = 64;
                demodSymbols = fskdemod(app.TransmittedSignal,M,4000,10,256000);
                DemodulatedData = de2bi(demodSymbols, bitsPerSymbol, 'left-msb');
            
            otherwise
                error('Unsupported demodulation type: %s', modulationType);
        end
    
    % Ensure output is a row vector of logical values
        DemodulatedData = reshape(DemodulatedData.',1,[]);
        l1 = length(DemodulatedData) - app.padm;
        % This gives the demodulated data removing the padding added while modulation.
        DemodulatedData = DemodulatedData(:,1:l1);


    end


function EncodedData = encodeData(app, data, codingType)

            % Implement encoding based on the selected coding scheme
    switch codingType
        case '(7,4) Hamming'
                    EncodedData = hammingEncode(app,data);
        case 'BCH (63,51)'
                    EncodedData = bchEncode(app,data, 63, 51);
        case 'BCH (127,64)'
                    EncodedData = bchEncode(app,data, 127, 64);
        case 'No Channel Coding'
                    EncodedData = data;
        otherwise
                    error('Unsupported coding type');
    end
end
    
function decodedData = bchDecode(app,DemodulatedData , n, k)
    % Ensure encodedData is a column vector
    if isrow(DemodulatedData)
        DemodulatedData = DemodulatedData'; % Convert row vector to column vector
    end
    
    % Create a BCH decoder object
    bchCode = comm.BCHDecoder(n, k);
    
    dem = bchCode(DemodulatedData);
    l = length(dem)-app.padc;
    dem = dem(1:l,:);
    decodedData = dem.';
    
end
function EncodedData = bchEncode(app,data, n, k)
    % Ensure data is a column vector
    if isrow(data)
        data = data'; % Convert row vector to column vector
    end
    
    % Create a BCH code object
    bchCode = comm.BCHEncoder(n, k);
    padLengthk =k-mod(length(data),k);
    app.padc =padLengthk;
    if padLengthk > 0
        data = [data;zeros(padLengthk,1)];
    end
    % Encode the data
    messageblocks = reshape(data,k,[]);

    numblocks = size(messageblocks,2);
    EncodedData =[];
    for i = 1:numblocks
        EncodedData = [EncodedData;bchCode(messageblocks(:,i))];
    end

    EncodedData = EncodedData';
    
end

function EncodedData = hammingEncode(app,data)

                    EncodedData = encode(app.FileData, 7, 4, 'hamming/binary');
end 
function decodedData = hammingDecode(app, DemodulatedData)
    
                decodedData = decode(DemodulatedData, 7, 4, 'hamming/binary');

    
end


function plotConstellation(app)
    
    delete(findall(app.ConstellationTab, 'Type', 'axes'));

    % Delete existing axes if present
    if isfield(app.ConstellationTab.Children, 'Type') && any(strcmp({app.ConstellationTab.Children.Type}, 'axes'))
        delete(app.ConstellationTab.Children(strcmp({app.ConstellationTab.Children.Type}, 'axes')));
    end

    % Create a new axes in the Constellation tab
    ax = uiaxes(app.ConstellationTab);
    ax.Position = [0 0 350 250]; % Adjust position and size as needed
        
    % Clear existing plot
    cla(ax);
    % Plot both ideal and noisy constellations
    hold(ax, 'on');
    
    % Plot noisy constellation points
    scatter(ax, real(app.TransmittedSignal), imag(app.TransmittedSignal), 25, 'r', 'filled');
    
    % Add title and labels
    xlabel(ax, 'In-Phase');
    ylabel(ax, 'Quadrature');
    grid(ax, 'on');

end

function plotPSD(app)
    delete(findall(app.PSDTab, 'Type', 'axes'));

    % Create a new axes in the PSD tab
    ax = uiaxes(app.PSDTab);
    ax.Position = [0 0 350 250]; % Adjust position and size as needed
    % Estimate and plot PSD using Welch's method
    [pxx, f] = pwelch(app.ModulatedSignal, [], [], [], 1000); % Adjust fs as needed
    plot(ax, f, 10*log10(pxx)); % Convert to dB scale
    
    title(ax, 'Power Spectral Density (PSD)');
    xlabel(ax, 'Frequency (Hz)');
    ylabel(ax, 'Power/Frequency (dB/Hz)');
    grid(ax, 'on');
end

function plotTimeDomain(app)
    % Clear previous axes
    delete(findall(app.TimeDomainTab, 'Type', 'axes'));

    % Create a new axes in the Time Domain tab
    ax = uiaxes(app.TimeDomainTab);
    ax.Position = [0 0 350 250]; % Adjust position and size as needed

    % Time vector based on sample rate and length of modulated signal
    fs = 1000; % Example sampling frequency (adjust as needed)
    t = (0:length(app.ModulatedSignal)-1) / fs; 

    % Plot the modulated signal in time domain
    plot(ax, t, real(app.ModulatedSignal)); % Plot real part of the signal
    
    % Set title and labels
    title(ax, 'Time Domain Modulated Signal');
    xlabel(ax, 'Time (s)');
    ylabel(ax, 'Amplitude');
    
    % Adjust x-axis limits to a specific range (e.g., first 1 second)
    durationToShow = 0.1; % Duration in seconds to display on the x-axis
    ax.XLim = [0, min(durationToShow, t(end))]; % Limit x-axis to specified duration
    
    % Automatically scale y-axis to signal amplitude
    ax.YLim = [min(real(app.ModulatedSignal)), max(real(app.ModulatedSignal))]; 
end
function plotBER(app)
    % Clear previous plots in the specified axes
    cla(app.UIAxes, 'reset');
    
    % Calculate theoretical BER for AWGN channel
    EbNo_dB = 0:15;
    EbNo = 10.^(EbNo_dB/10);
    
    
    % Theoretical BER based on selected modulation
    switch app.ModulationDropDown.Value
        case 'QPSK'
            BER = berawgn(EbNo_dB,'psk',4,'nondiff');
            K =2;
        case '16 QAM'
            BER = berawgn(EbNo_dB,'qam',16,'coherent');
            K =4;
        case '64 QAM'
            BER = berawgn(EbNo_dB,'qam',64,'coherent');
            K = 6;
        case '16 FSK'
            BER = berawgn(EbNo_dB,'fsk',16,'noncoherent');
            K = 4;

        case '64 FSK'
            BER = berawgn(EbNo_dB,'fsk',64,'noncoherent');
            K = 6;


        otherwise
            error('Unsupported modulation type');
    end
    
    SNR = 10*log10(EbNo*K);

    % Hold on to allow multiple plots
    hold(app.UIAxes, 'on');
    
    % Plot the theoretical BER curve
    semilogy(app.UIAxes, EbNo_dB, BER, 'b-', 'LineWidth', 2, 'DisplayName', 'Theoretical BER');
    
    % Preallocate array for simulated BER
    ber_withoutcoding = zeros(length(EbNo_dB),1);
    if ismember(app.ChannelCodingDropDown.Value, {'BCH (127,64)', 'BCH (63,51)', '(7,4) Hamming'})
       ber_with_coding =  zeros(length(EbNo_dB),1);
    end
    % Simulated BER calculation
    for i = 1:length(EbNo_dB)
        
        ber_withoutcoding(i) = ber_function_withoutcoding(app,SNR(i));
        if ismember(app.ChannelCodingDropDown.Value, {'BCH (127,64)', 'BCH (63,51)', '(7,4) Hamming'})
           ber_with_coding(i) = ber_function_with_coding(app,SNR(i));
           sprintf('Eb/No = %.1f dB, BER = %.3e, BER with coding = %.3e \n', EbNo_dB(i), ber_withoutcoding(i),ber_with_coding(i))
        end
    end
    
            
    % Plot the simulated BER
    semilogy(app.UIAxes, EbNo_dB, ber_withoutcoding, 'r-o', 'LineWidth', 2, 'DisplayName', 'Simulated BER(Uncoded)');
    if ismember(app.ChannelCodingDropDown.Value, {'BCH (127,64)', 'BCH (63,51)', '(7,4) Hamming'})
       semilogy(app.UIAxes, EbNo_dB, ber_with_coding, 'g-o', 'LineWidth', 2, 'DisplayName', 'Simulated BER(Coded)');
    end
    % Configure plot
    grid(app.UIAxes, 'on');
    xlabel(app.UIAxes, 'Eb/No (dB)');
    ylabel(app.UIAxes, 'Bit Error Rate (BER)');
    title(app.UIAxes, 'BER Theoretical vs Simulated(Coded vs. Uncoded)');
    legend(app.UIAxes, 'show');
    
    % Set appropriate axis limits
    app.UIAxes.XLim = [0 15];
    app.UIAxes.YLim = [1e-20 1];
    app.UIAxes.YScale = 'log';
    app.UIAxes.GridLineStyle = ':';
    
    hold(app.UIAxes, 'off');
    drawnow;
    simulation_results(app);
end

% Helper function to get modulation order
function order = getModulationOrder(app,modType)
    switch modType
        case 'QPSK'
            order = 4;
        case '16 QAM'
            order = 16;
        case '64 QAM'
            order = 64;
        case '16 FSK'
            order = 16;
        case '64 FSK'
            order = 64;
        otherwise
            order = 2; % Default to BPSK
    end
end

function ber = ber_function_withoutcoding(app, SNR)
    % Get modulation order
    modOrder = getModulationOrder(app,app.ModulationDropDown.Value);
    
    
    % Reshape data if needed for QPSK
    

    modulatedSignal = app.modulateData(app.FileData, app.ModulationDropDown.Value, SNR);
    transmittedSignal = awgn(modulatedSignal,SNR);
    demodulatedData = demodulateData(app, transmittedSignal, char(app.ModulationDropDown.Value));
        
        % Ensure data is in the correct shape for comparison
        if ~isequal(size(app.FileData), size(demodulatedData))
                if size(demodulatedData, 1) == 1
                    demodulatedData = demodulatedData';
                end
                % Reshape if necessary
                if numel(app.FileData) == numel(demodulatedData)
                    demodulatedData = reshape(demodulatedData, size(app.FileData));
                end
        end
        
        
        [numErrors,ber] = biterr(app.FileData, demodulatedData,1);
   
end

function decodedData = decodeData(app, DemodulatedData, codingType)
            % Implement decoding based on the selected coding scheme
            switch codingType
                case '(7,4) Hamming'
                    decodedData = hammingDecode(app,DemodulatedData);
                case 'BCH (63,51)'
                    decodedData = bchDecode(app,DemodulatedData, 63, 51);
                case 'BCH (127,64)'
                    decodedData = bchDecode(app,DemodulatedData, 127, 64);
                case 'No Channel Coding'
                    decodedData = DemodulatedData;
                otherwise
                    error('Unsupported coding type');
            end
        end


function displaySourceData(app)
            % Display the source data based on the selected type
            switch string(app.SourceDataType)
                case 'Random Bits'
                    disp(app.FileData) % Display random bits in command window
                    app.TextArea.Value = app.FileData;
                
                case 'Text File'
                    text = char(bin2dec(reshape(char(app.FileData + '0'), 8, [])'));
                    app.TextArea.Value = char(text) % Show text in TextArea
                case 'Grayscale Image'
                    ImgData = uint8(bin2dec(reshape(char(app.FileData + '0'), 8, [])));
                    imgSize = sqrt(numel(ImgData));
                    img = reshape(ImgData, [imgSize, imgSize]);
                    imshow(img, 'Parent', app.UIAxes2); % Display reconstructed image
                case 'Audio Signal'
                    audioData = typecast(uint8(bin2dec(reshape(char(app.FileData + '0'), 8, []))), 'single');
                    sound(audioData, 44100); % Play audio
            end
        end

function AudioPlayerButtonPushed(app, event)
            % Play audio data if available
            if ~isempty(app.FileData) && strcmp(app.SourceDataType, 'Audio Signal')
                audioData = typecast(uint8(bin2dec(reshape(char(app.FileData + '0'), 8, []))), 'single');
                sound(audioData, 44100);
            else
                uialert(app.UIFigure, 'No audio data available.', 'Audio Playback Error');
            end
        end

        
function ber_coded = ber_function_with_coding(app,SNR)
    
            % Generate data ensuring proper length
            codingscheme = app.ChannelCodingDropDown.Value;
            m = getModulationOrder(app,app.ModulationDropDown.Value);
            bitsPerSymbol = log2(m);
            
            codedData = encodeData(app,app.FileData,char(app.ChannelCodingDropDown.Value));


            modulatedSignal = app.modulateData(codedData, app.ModulationDropDown.Value, SNR);
            transmittedSignal = awgn(modulatedSignal,SNR);
            demodulatedData = demodulateData(app, transmittedSignal, char(app.ModulationDropDown.Value));

            decodedData = decodeData(app,demodulatedData,char(app.ChannelCodingDropDown.Value));


            % Ensure data is in the correct shape for comparison
            if ~isequal(size(app.FileData), size(decodedData))
                if size(decodedData, 1) == 1
                    decodedData= decodedData';
                end
                % Reshape if necessary
                if numel(app.FileData) == numel(decodedData)
                    decodedData = reshape(decodedData, size(app.FileData));
                end
            end
        
            
            [numErrors,ber_coded] = biterr(app.FileData, decodedData,1);
           

        end
    end
    
    methods (Access = public)
        
        function r = simulation_results(app)
            SNR = 0:0.5:7.5;       
            l =length(SNR);
            figure;
            for i=1:l
                switch char(app.SourceDataDropDown.Value)
                    case 'Grayscale Image'
                        t = modulateData(app,app.EncodedData,char(app.ModulationDropDown.Value),SNR(i));
                        r = demodulateData(app,app.TransmittedSignal,char(app.ModulationDropDown.Value));
                        d = decodeData(app,r,app.ChannelCodingDropDown.Value);
                        padLength = mod(8 - mod(length(d), 8), 8);
                        if padLength < 8
                            d = [d, zeros(1, padLength)];
                        end
                    
                        bytes = reshape(d, 8, [])';
                        imgdata = uint8(bi2de(bytes, 'left-msb'));
                        imgSize = floor(sqrt(numel(imgdata)));
                        img = reshape(imgdata(1:imgSize^2), [imgSize, imgSize]);
                        
                        subplot(4,4,i);
                        imshow(img);
                        title(SNR(i))
                    case {'Random Bits';'Text File';'Audio Signal'}
                        close


                end
            end                    
            
        end

    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ChannelCodingDropDown
        function ChannelCodingDropDownValueChanged(app, event)
            value = app.ChannelCodingDropDown.Value;
            switch value
                case '(7,4) Hamming'
                    app.EncodedData = hammingEncode(app,app.FileData);

                case 'BCH (63,51)'
                    app.EncodedData = bchEncode(app,app.FileData, 63, 51);
                
                case 'BCH (127,64)'
                    app.EncodedData = bchEncode(app,app.FileData, 127, 64);
                
            end
    
            uialert(app.UIFigure, 'Encoding completed.', 'Success','Icon', "success");
        end

        % Value changed function: SourceDataDropDown
        function SourceDataDropDownValueChanged(app, event)
            value = app.SourceDataDropDown.Value;
            % Process data based on selection
            switch value
                case 'Random Bits'
                    app.FileData = randi([0, 1], 1, 1000000); % Generate random bits
                    
                    app.TextArea.Value = {sprintf('%d', app.FileData)};
                

                case 'Text File'
                    [file, path] = uigetfile('*.txt', 'Select a text file');
                    if isequal(file, 0)
                        uialert(app.UIFigure, 'No file selected.', 'File Selection');
                        return;
                    end
                    % Load and convert text to binary
                    textData = fileread(fullfile(path, file));
                    app.FileData = double(reshape(de2bi(uint8(textData), 8)', [], 1));
                    app.TextArea.Value = char(textData); % Show text in TextArea
                case 'Grayscale Image'
                    [file, path] = uigetfile({'*.jpg;*.png;*.bmp'}, 'Select an image file');
                    if isequal(file, 0)
                        uialert(app.UIFigure, 'No file selected.', 'File Selection');
                        return;
                    end
                    img = imread(fullfile(path, file));
                    app.FileData = dec2bin(img(:), 8)' - '0';
                    app.FileData = app.FileData(:)';
                    imshow(img, 'Parent', app.UIAxes2);
                case 'Audio Signal'
                    [file, path] = uigetfile({'*.wav; *.mp3'}, 'Select an audio file');
                    if isequal(file, 0)
                        uialert(app.UIFigure, 'No file selected.', 'File Selection');
                        return;
                    end
                    [audioData, app.Fs] = audioread(fullfile(path, file));
                    app.FileData = dec2bin(typecast(single(audioData(:)), 'uint8'), 8)' - '0';
                    app.FileData = app.FileData(:)';
                
            end


            displaySourceData(app)
            uialert(app.UIFigure, 'Data loaded successfully.',"Success",'Icon', 'Success');
            
        end

        % Button pushed function: ModulateButton
        function ModulateButtonPushed(app, event)

    try
        % Check if FileData is empty
        if isempty(app.FileData)
            uialert(app.UIFigure, 'No source data available. Please load data first.', 'Data Error');
            return;
        end
        
        % Get selected coding type
        codingType = app.ChannelCodingDropDown.Value;
        
        % Special handling for text data
        if strcmp(app.SourceDataDropDown.Value, 'Text File')
            % Ensure FileData is in proper binary format
            if ~islogical(app.FileData)
                % Convert to logical array if it's not already
                app.FileData = logical(app.FileData);
            end
            
            % Ensure data length is multiple of 8
            padLength = mod(8 - mod(length(app.FileData), 8), 8);
            if padLength < 8
                app.FileData = [app.FileData, false(1, padLength)];
            end
        end
        
        % Encode data based on selected coding scheme
        app.EncodedData = app.encodeData(app.FileData, codingType);
        
        % Check if EncodedData is empty after encoding
        if isempty(app.EncodedData)
            uialert(app.UIFigure, 'Encoding failed. Please check your input data.', 'Encoding Error');
            return;
        end
        
        % Modulate based on selected modulation scheme
        app.ModulatedSignal = app.modulateData(app.EncodedData, app.ModulationDropDown.Value, app.SNRindBEditField.Value);
        
        % Update plots
        plotConstellation(app);
        plotTimeDomain(app);
        plotPSD(app);
        
        uialert(app.UIFigure, 'Modulation completed.',"Success",'Icon', 'Success');
        
    catch e
        errorMsg = sprintf('Error during modulation: %s\n', e.message);
        uialert(app.UIFigure, errorMsg, 'Error');
        disp(getReport(e));
    end
        end

        % Button pushed function: DemodulateButton
        function DemodulateButtonPushed(app, event)

    try
        % Verify modulated signal exists
        if isempty(app.ModulatedSignal)
            uialert(app.UIFigure, 'No modulated signal available. Please modulate data first.', 'Error');
            return;
        end
        
        % Get current modulation type and ensure it's a character array
        modType = char(app.ModulationDropDown.Value);
        
        % Perform demodulation
        app.DemodulatedData = demodulateData(app, app.TransmittedSignal, modType);
        
        % Get current coding type
        codingType = app.ChannelCodingDropDown.Value;
        
        % Decode the data
        if ~isempty(codingType)
            app.decodedBits = app.decodeData(app.DemodulatedData, codingType);
            % Process based on source data type
            sourceType = app.SourceDataDropDown.Value;
            
            switch sourceType
        case 'Text File'
    % Debugging step: Check the initial length of decodedBits
    
    % Remove any trailing zeros that might cause issues
    while length(app.decodedBits) > 7 && all(app.decodedBits(end-6:end) == 0)
        app.decodedBits(end-6:end) = [];
    end
    
    % Debugging step: After trimming trailing zeros
    
    % Ensure length is a multiple of 7 (complete characters)
    if mod(length(app.decodedBits), 7) ~= 0
        padLength = 7 - mod(length(app.decodedBits), 7);
        app.decodedBits = [app.decodedBits, zeros(1, padLength)];  % Pad with zeros
    end
    
    % Debugging step: After padding
    bitSequence = reshape(app.decodedBits(1:numel(app.FileData)), 8, [])';
            charValues = bi2de(double(bitSequence), 'right-msb');
            Text = char(charValues)';
    % Convert bits to characters (7 bits per character)
    bytes = reshape(app.decodedBits, 7, [])';  % Reshaping to 7-bit columns, row-wise
    
    % Debugging step: Check the reshaped byte array
    
    % Convert to ASCII values (decimal representation)
    decValues = bi2de(bytes, 'left-msb');
    
    % Debugging step: Check the ASCII decimal values
    disp(decValues);
    
    % Convert to text (use ASCII values to characters)
    text = char(decValues);
    
    % Update the TextArea (ensure text is in a cell array)
    app.TextArea_2.Value = Text;  % Update TextArea value with decoded text


                % ... rest of the cases remain the same ...
                case 'Grayscale Image'
                    % Process image data
                    padLength = mod(8 - mod(length(app.decodedBits), 8), 8);
                    if padLength < 8
                        app.decodedBits = [app.decodedBits, zeros(1, padLength)];
                    end
                    
                    bytes = reshape(app.decodedBits, 8, [])';
                    app.imgData = uint8(bi2de(bytes, 'left-msb'));
                    
                    imgSize = floor(sqrt(numel(app.imgData)));
                    img = reshape(app.imgData(1:imgSize^2), [imgSize, imgSize]);
                    
                    imshow(img, 'Parent', app.UIAxes2_2);
                    
                case 'Audio Signal'
                    % Process audio data
                    padLength = mod(8 - mod(length(app.decodedBits), 8), 8);
                    if padLength < 8
                        app.decodedBits = [app.decodedBits, zeros(1, padLength)];
                    end
                    
                    bytes = reshape(app.decodedBits, 8, [])';
                    audioData = typecast(uint8(bi2de(bytes, 'left-msb')), 'single');
                    
                    audioData = audioData / max(abs(audioData));
                    sound(audioData, app.Fs);
                    app.TextArea.Value = {'Audio data ready for playback'};
                    
                case 'Random Bits'
                    bitsStr = sprintf('%d', app.decodedBits);
                    app.TextArea_2.Value = {bitsStr};
            end
        end
    plotBER(app);
    catch e
        errorMsg = sprintf('Error in demodulation process:\n%s', e.message);
        uialert(app.UIFigure, errorMsg, 'Error');
        disp(getReport(e));
    end
    uialert(app.UIFigure, 'Demodulation Process Done!!' ,'Success.','Icon','success');

        end

        % Button pushed function: AudioPlayerSourceButton
        function AudioPlayerSourceButtonPushed2(app, event)
            if (~isempty(app.FileData)) && strcmp(app.SourceDataDropDown.Value, 'Audio Signal') && isempty(app.DemodulatedData)
                bytes = reshape(app.FileData, 8, [])';
                audioData = typecast(uint8(bi2de(bytes, 'left-msb')), 'single');
                sound(audioData, app.Fs);
            else
                uialert(app.UIFigure, 'No audio data available.', 'Audio Playback Error');
            end
            
        end

        % Button pushed function: AudioPlayerDemodulatedButton
        function AudioPlayerDemodulatedButtonPushed(app, event)
            if (~isempty(app.DemodulatedData)) && strcmp(app.SourceDataDropDown.Value, 'Audio Signal')
                    
                    bytes = reshape(app.decodedBits, 8, [])';
                    audioData = typecast(uint8(bi2de(bytes, 'left-msb')), 'single');
                    
                    sound(audioData, app.Fs);
                    app.TextArea.Value = {'Audio data ready for playback'};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'BER Theoritcal and Simulated ')
            xlabel(app.UIAxes, 'Eb/No(in dB)')
            ylabel(app.UIAxes, 'Pb, Probability of Bit Error')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.SourceDataDropDownLabel = uilabel(app.UIFigure);
            app.SourceDataDropDownLabel.Text = 'Source Data';
            app.SourceDataDropDown = uidropdown(app.UIFigure);
            app.SourceDataDropDown.Items = {'Random Bits', 'Text File', 'Grayscale Image', 'Audio Signal', 'Select'};
            app.SourceDataDropDown.ValueChangedFcn = createCallbackFcn(app, @SourceDataDropDownValueChanged, true);
            app.SourceDataDropDown.Value = 'Select';
            app.ChannelCodingDropDownLabel = uilabel(app.UIFigure);
            app.ChannelCodingDropDownLabel.Text = 'Channel Coding';
            app.ChannelCodingDropDown = uidropdown(app.UIFigure);
            app.ChannelCodingDropDown.Items = {'BCH (127,64)', 'BCH (63,51)', '(7,4) Hamming', 'No Channel Coding', 'Select'};
            app.ChannelCodingDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelCodingDropDownValueChanged, true);
            app.ChannelCodingDropDown.Value = 'Select';
            app.ModulationDropDownLabel = uilabel(app.UIFigure);
            app.ModulationDropDownLabel.Text = 'Modulation';
            app.ModulationDropDown = uidropdown(app.UIFigure);
            app.ModulationDropDown.Items = {'QPSK', '16 QAM', '64 QAM', '16 FSK', '64 FSK', 'Select'};
            app.ModulationDropDown.Value = 'Select';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConstellationTab = uitab(app.TabGroup);
            app.ConstellationTab.Title = 'Constellation';
            app.PSDTab = uitab(app.TabGroup);
            app.PSDTab.Title = 'PSD';
            app.TimeDomainTab = uitab(app.TabGroup);
            app.TimeDomainTab.Title = 'Time Domain';
            app.DemodulateButton = uibutton(app.UIFigure, 'push');
            app.DemodulateButton.ButtonPushedFcn = createCallbackFcn(app, @DemodulateButtonPushed, true);
            app.DemodulateButton.Text = 'Demodulate';
            app.TextArea = uitextarea(app.UIFigure);
            app.AudioPlayerSourceButton = uibutton(app.UIFigure, 'push');
            app.AudioPlayerSourceButton.ButtonPushedFcn = createCallbackFcn(app, @AudioPlayerSourceButtonPushed2, true);
            app.AudioPlayerSourceButton.Text = 'Audio Player(Source)';
            app.ModulateButton = uibutton(app.UIFigure, 'push');
            app.ModulateButton.ButtonPushedFcn = createCallbackFcn(app, @ModulateButtonPushed, true);
            app.ModulateButton.Text = 'Modulate';
            app.SNRindBEditFieldLabel = uilabel(app.UIFigure);
            app.SNRindBEditFieldLabel.Text = 'SNR (in dB)';
            app.SNRindBEditField = uieditfield(app.UIFigure, 'numeric');
            app.Group13ChandrahasVeeragandham2022A3PS0675GLabel = uilabel(app.UIFigure);
            app.Group13ChandrahasVeeragandham2022A3PS0675GLabel.Text = {'Group 13: Chandrahas Veeragandham, Harshith Kambampati'; 'Tushar Nair'};
            app.TextOutputLabel = uilabel(app.UIFigure);
            app.TextOutputLabel.Text = 'Text Output';
            app.ImageInputLabel = uilabel(app.UIFigure);
            app.ImageInputLabel.Text = 'Image Input';
            app.ImageOutputLabel_2 = uilabel(app.UIFigure);
            app.ImageOutputLabel_2.Text = 'Image Output';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextInputLabel = uilabel(app.UIFigure);
            app.TextInputLabel.Text = 'Text Input';
            app.AudioPlayerDemodulatedButton = uibutton(app.UIFigure, 'push');
            app.AudioPlayerDemodulatedButton.ButtonPushedFcn = createCallbackFcn(app, @AudioPlayerDemodulatedButtonPushed, true);
            app.AudioPlayerDemodulatedButton.Text = 'Audio Player(Demodulated)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Group_13_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end